classdef centerVis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StartPlottingButton_2  matlab.ui.control.Button
        StopPlottingButton     matlab.ui.control.Button
        StartPlottingButton    matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        device % serial port
        angle
        angRate
        time
        line
        plotBool
    end
    
    methods (Access = private)
        
        function plotData(app)
                while app.plotBool
                    % Read data from the serial port
                    data = readline(app.device);                    
                    
                    % Parse to get the distance
                    charArr = convertStringsToChars(data);

                    % Parse to get the servo data and the distance data 
                    distVal = '';
                    for i = 1:length(charArr)
                        if(charArr(i) == 'D' || charArr(i) == '=' || charArr(i) == 'c' || charArr(i) == 'm') % Do nothing
                            continue;
                        end                        
                        if(charArr(i) == ';') % Break the loop
                            i = i + 1;
                            break
                        end 
                        distVal(end + 1) = charArr(i);
                    end
                    
                    % Servo position
                    servoVal = charArr(i:end);

                    dist = str2double(distVal);        % Distance numeric
                    servo = str2double(servoVal);   % Servo Position
                    
                    % Skip if dist is too high
                    if(dist >= 1000)
                        continue;
                    end

                    % Calculate anlge based on servo position
                    % 4000 --> 0, 8000 --> pi/2
                    tempVal = (servo - 4000) / 4000;    % Number between 0 and 1                    
                    app.angle = tempVal * pi/2;

                    % Position
                    xPos = dist*cos(app.angle);
                    yPos = dist*sin(app.angle);                  
                    
                    % Plot points
                    addpoints(app.line, xPos, yPos);
                    drawnow;

                    % Update time
                    app.time = toc;
                    
                    % Pause for a short time to avoid high CPU usage
                    pause(0.1);
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, device)
            app.device = device;    % Serial port device
        end

        % Button pushed function: StartPlottingButton_2
        function StartPlottingButton_2Pushed(app, event)
            % Create animated line for figure
            app.line = animatedline(app.UIAxes, "LineStyle","none", 'Marker','x');

            % Start plotting         
            app.plotBool = 1;
            app.time = 0;
            app.angle = 0;
            app.angRate = pi/20;    % 90 deg in 10 seconds

            % Send SCAN_OFF command to reset position
            command = "SCAN_OFF"; % Stop scanning

            % Send the command
            writeline(app.device, command);

            % Send SCAN_ON command
            command = "SCAN_ON"; % Start scanning

            % Send the command
            writeline(app.device, command);
            tic % Start timing

            % Send FAST_ON command
            command = "FAST_ON"; % Start sending data

            % Send the command
            writeline(app.device, command);

            % Send POINT_ON command
            command = "POINT_ON"; % Send Servo data

            % Send the command
            writeline(app.device, command);

            % Call plotting function
            app.plotData();
            pause on;
        end

        % Button pushed function: StopPlottingButton
        function StopPlottingButtonPushed(app, event)
            % Stop plotting
            app.plotBool = 0;

            % Send SCAN_OFF command to reset position
            command = "SCAN_OFF"; % Stop scanning

            % Send the command
            writeline(app.device, command);

            % Send FAST_OFF command
            command = "FAST_OFF"; % Start sending data            

            % Send the command
            writeline(app.device, command);

            % Send POINT_OFF command
            command = "POINT_OFF"; % Stop Servo data

            % Send the command
            writeline(app.device, command);

            % Pause
            pause on;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '2D Distance Point Cloud')
            xlabel(app.UIAxes, 'X Dist (cm)')
            ylabel(app.UIAxes, 'Y Dist (cm)')
            zlabel(app.UIAxes, 'Z')
            app.StartPlottingButton = uibutton(app.UIFigure, 'push');
            app.StartPlottingButton.Text = 'Start Plotting';
            app.StopPlottingButton = uibutton(app.UIFigure, 'push');
            app.StopPlottingButton.ButtonPushedFcn = createCallbackFcn(app, @StopPlottingButtonPushed, true);
            app.StopPlottingButton.Text = 'Stop Plotting';
            app.StartPlottingButton_2 = uibutton(app.UIFigure, 'push');
            app.StartPlottingButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartPlottingButton_2Pushed, true);
            app.StartPlottingButton_2.Text = 'Start Plotting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = centerVis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end