classdef distOverTime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StopPlottingButton     matlab.ui.control.Button
        StartPlottingButton    matlab.ui.control.Button
        DistanceOverTimeLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        device % Description
        time    % Time
        line    % Animated line
        plotBool    % Boolean to plot
    end
    
    methods (Access = private)
        
        % This function will plot incoming data from the LiDAR sensor
        function plotData(app)
            while app.plotBool
                % Read data from the serial port
                data = readline(app.device);

                % Parse to get the distance
                charArr = convertStringsToChars(data);

                % Parse to get the servo data and the distance data 
                distVal = '';
                for i = 1:length(charArr)
                    if(charArr(i) == 'D' || charArr(i) == '=' || charArr(i) == 'c' || charArr(i) == 'm') % Do nothing
                        continue;
                    end                        
                    if(charArr(i) == ';') % Break the loop
                        i = i + 1;
                        break
                    end 
                    distVal(end + 1) = charArr(i);
                end
                
                dist = str2double(distVal);        % Distance numeric
                
                % Skip if dist is too hight
                if(dist >= 1000)
                    continue;
                end
                
                % Plot points
                addpoints(app.line, app.time, dist);
                drawnow;

                % Update time
                app.time = toc;
                
                % Pause for a short time to avoid high CPU usage
                pause(0.1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, device)
            app.device = device;    % Serial port device
        end

        % Button pushed function: StartPlottingButton
        function StartPlottingButtonPushed(app, event)
            % Create animated line for figure
            app.line = animatedline(app.UIAxes);

            % Start collection
            command = "FAST_ON";

            % Send the command
            writeline(app.device, command);

            % No Servo Data
            command = "POINT_OFF";

            % Send the command
            writeline(app.device, command);

            % Start plotting
            tic
            app.plotBool = 1;
            app.time = 0;

            % Call plotting function
            app.plotData();
            pause on;
        end

        % Button pushed function: StopPlottingButton
        function StopPlottingButtonPushed(app, event)
            % Stop plotting
            app.plotBool = 0;

            % Send SCAN_OFF command to reset position
            command = "SCAN_OFF"; % Stop scanning

            % Send the command
            writeline(app.device, command);

            % Send FAST_OFF command
            command = "FAST_OFF"; % Start sending data            

            % Send the command
            writeline(app.device, command);

            % Send POINT_OFF command
            command = "POINT_OFF"; % Stop Servo data

            % Send the command
            writeline(app.device, command);

            % Pause
            pause on;           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Distance (cm)')
            zlabel(app.UIAxes, 'Z')
            app.DistanceOverTimeLabel = uilabel(app.UIFigure);
            app.DistanceOverTimeLabel.Text = 'Distance Over Time';
            app.StartPlottingButton = uibutton(app.UIFigure, 'push');
            app.StartPlottingButton.ButtonPushedFcn = createCallbackFcn(app, @StartPlottingButtonPushed, true);
            app.StartPlottingButton.Text = 'Start Plotting';
            app.StopPlottingButton = uibutton(app.UIFigure, 'push');
            app.StopPlottingButton.ButtonPushedFcn = createCallbackFcn(app, @StopPlottingButtonPushed, true);
            app.StopPlottingButton.Text = 'Stop Plotting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = distOverTime(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end